classdef appVisualLite2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ExitButton                matlab.ui.control.Button
        SelectPlotsGeneralCoordinatesPanel  matlab.ui.container.Panel
        ApplyButton               matlab.ui.control.StateButton
        SteerAngledegCheckBox     matlab.ui.control.CheckBox
        RolldegCheckBox           matlab.ui.control.CheckBox
        YawdegCheckBox            matlab.ui.control.CheckBox
        FilenameEditField         matlab.ui.control.EditField
        FilenameEditFieldLabel    matlab.ui.control.Label
        RiderEditField            matlab.ui.control.EditField
        RiderEditFieldLabel       matlab.ui.control.Label
        GearEditField             matlab.ui.control.NumericEditField
        GearEditFieldLabel        matlab.ui.control.Label
        SpeedkmhEditField         matlab.ui.control.EditField
        SpeedkmhEditFieldLabel    matlab.ui.control.Label
        ExperimentEditField       matlab.ui.control.EditField
        ExperimentEditFieldLabel  matlab.ui.control.Label
        BikeEditField             matlab.ui.control.EditField
        BikeEditFieldLabel        matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        TimesSlider               matlab.ui.control.Slider
        TimesSliderLabel          matlab.ui.control.Label
        uipanel17                 matlab.ui.container.Panel
        reset_button              matlab.ui.control.Button
        stop_button               matlab.ui.control.Button
        play_button               matlab.ui.control.Button
        ViewOrientation_uipanel   matlab.ui.container.Panel
        both_pushbutton           matlab.ui.control.Button
        rider_pushbutton          matlab.ui.control.Button
        bicycle_pushbutton        matlab.ui.control.Button
        Side_uipanel              matlab.ui.container.ButtonGroup
        top_radiobutton           matlab.ui.control.RadioButton
        Front_radiobutton         matlab.ui.control.RadioButton
        Side_radiobutton          matlab.ui.control.RadioButton
        Dimension_uipanel         matlab.ui.container.ButtonGroup
        radiobutton2              matlab.ui.control.RadioButton
        D2_radiobutton            matlab.ui.control.RadioButton
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        Timer % object that executes the step function of the simulation at fixed step dt
        Data % loaded data
        Length % data length --> It is only considered the shorter set of data that corresponds to the bike rides modes of motion in time
        Index = 1 % current index
        Stop    logical = 1 % stop bool
        UserData int16 = 3; % Condition for set between rider (1), bike (2) or both (3) 
        rider string % rider name
        dt      double = 0.01; % sample time
        FileName    string % file selected
        dPlotX  double = 5; % Delta time for set limit of gen. coord. plot
        c_modes % Contains which modes od motion are selected for representation

        theta = [0:pi/10:2*pi]; % steps in angle to make wheel

        % Bike and wheels elements for plot
        frontfork
        handlebar
        stem
        rearfork
        frontwheel
        rearwheel

        % Rider elements for plot
        handlepuntr
        handlerlba
        handlela
        handlell
        xn
        yn
        zn
        t

        jumpProces = 1;

        plotx % plot of horizontal line

    end
    

    methods (Access = private)
        
        function DeactivateAll(app)
            app.TimesSlider.Enable = 'off';
            app.play_button.Enable = 'off';
            app.stop_button.Enable = 'off';
            app.reset_button.Enable = "off";
            app.ViewOrientation_uipanel.Enable = "off";
            app.SelectPlotsGeneralCoordinatesPanel.Enable = "off";

        end

        function ActivateAll(app)
            app.TimesSlider.Enable = 'on';
            app.play_button.Enable = 'on';
            app.stop_button.Enable = 'on';
            app.reset_button.Enable = "on";
            app.ViewOrientation_uipanel.Enable = "on";
            app.SelectPlotsGeneralCoordinatesPanel.Enable = "on";
        end

        function UpdateText(app)
            app.FilenameEditField.Value = app.FileName;
            app.BikeEditField.Value = app.Data.bike;
            app.ExperimentEditField.Value = app.Data.condition;
            app.SpeedkmhEditField.Value = num2str(app.Data.V);
            app.GearEditField.Value = app.Data.gearing;
            app.RiderEditField.Value = app.rider;
        end
        
        function [lim] = FindLims(app, jump)        
            
            % Find limits to be set in the General coordinates plot ||
            % Improve efficiency of the simulation as well the
            % visualization of details
            for i = 0:jump:app.Data.t(end)
                limInf = i;
                limSup = i + jump;
        
                if app.Data.t(app.Index) >= limInf && app.Data.t(app.Index) < limSup
                    lim = [limInf limSup];
                    return;
                end
            end
        
            lim = [0 jump]; % Default in othercase
            
        end

        function firstQplots(app)
            
            cla(app.UIAxes2, 'reset')
            disableDefaultInteractivity(app.UIAxes2); % Deshabilita la interactividad en los ejes
            app.UIAxes2.HitTest = 'off';

            jump = 5;
            hold(app.UIAxes2, "on")
            if get(app.YawdegCheckBox ,'Value') 
                plot(app.UIAxes2, app.Data.t(1:jump:end), rad2deg(app.Data.q(1:jump:end,3)),'b',LineWidth=1.2);
       
            end
            if get(app.RolldegCheckBox ,'Value') 
                plot(app.UIAxes2, app.Data.t(1:jump:end), rad2deg(app.Data.q(1:jump:end,4)),'r',LineWidth=1.2);
  
            end
            if get(app.SteerAngledegCheckBox ,'Value')
                plot(app.UIAxes2, app.Data.t(1:jump:end), rad2deg(app.Data.q(1:jump:end,7)),'g',LineWidth=1.2)
  
            end
            grid(app.UIAxes2, "on")
            title(app.UIAxes2, 'General coordinates')
            ylabel(app.UIAxes2, 'angles [deg]')
            xlabel(app.UIAxes2, 'time [s]')
            app.plotx = xline(app.UIAxes2 ,app.Data.t(app.Index),'--k');
        end

        function timeplotbike(app)
            n = min(app.Length, app.Index);
            x = -app.xn(n, :); y = -app.yn(n, :); z = -app.zn(n, :);
            % bicycle frame
            set(app.frontfork,'XData',x([21 33 27]),'YData',y([21 33 27]),'ZData',z([21 33 27]));
            set(app.handlebar,'XData',x([23 38 29]),'YData',y([23 38 29]),'ZData',z([23 38 29]));
            set(app.stem,'XData',x([33 38]),'YData',y([33 38]),'ZData',z([33 38]));
            set(app.rearfork,'XData',x([37 25 26 31 37 33 26 37]),'YData',y([37 25 26 31 37 33 26 37]),'ZData',z([37 25 26 31 37 33 26 37]));
                      
            % wheels
            Sf = [(x(32)-x(40)) (y(32)-y(40)) (z(32)-z(40))];
            Pf = [(x(32)-x(27)) (y(32)-y(27)) (z(32)-z(27))];
            R = cross(Sf,Pf);
            Rnorm = sqrt(R(1)^2+R(2)^2+R(3)^2);
            R = R/Rnorm;
             
            qx = x(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(1) + sin(app.theta)*Sf(1);
            qy = y(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(2) + sin(app.theta)*Sf(2);
            qz = z(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(3) + sin(app.theta)*Sf(3);
            set(app.frontwheel,'XData',qx','YData', qy,'ZData', qz);
            Sr = [(x(36)-x(39)) (y(36)-y(39)) (z(36)-z(39))];
            Pr = [(x(36)-x(31)) (y(36)-y(31)) (z(36)-z(31))];
            R = cross(Sr,Pr);
            Rrnorm = sqrt(R(1)^2+R(2)^2+R(3)^2);
            Rr = R/Rrnorm;
            sx = x(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(1) + sin(app.theta)*Sr(1);
            sy = y(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(2) + sin(app.theta)*Sr(2);
            sz = z(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(3) + sin(app.theta)*Sr(3);
            set(app.rearwheel,'XData',sx,'YData',sy,'ZData',sz);

        end

        function timeplotboth(app)
            n = min(app.Length, app.Index);
            x = -app.xn(n, :); y = -app.yn(n, :); z = -app.zn(n, :);    
            % rider
            % rider
            set(app.handlepuntr,'XData',x(1:20),'YData',y(1:20), 'ZData',z(1:20));
            set(app.handlerlba,'XData',x([1 2 3 4 9 10 11 15 14 13]),'YData',y([1 2 3 4 9 10 11 15 14 13]),'ZData',z([1 2 3 4 9 10 11 15 14 13]));
            set(app.handlela, 'XData',x([11 19 18 17]),'YData',y([11 19 18 17]),'ZData',z([11 19 18 17]));
            set(app.handlell, 'XData',x([5 6 7 8 9]),'YData',y([5 6 7 8 9]),'ZData',z([5 6 7 8 9]));
            
            % bicycle frame
            set(app.frontfork,'XData',x([21 33 27]),'YData',y([21 33 27]),'ZData',z([21 33 27]));
            set(app.handlebar,'XData',x([23 38 29]),'YData',y([23 38 29]),'ZData',z([23 38 29]));
            set(app.stem,'XData',x([33 38]),'YData',y([33 38]),'ZData',z([33 38]));
            set(app.rearfork,'XData',x([37 25 26 31 37 33 26 37]),'YData',y([37 25 26 31 37 33 26 37]),'ZData',z([37 25 26 31 37 33 26 37]));
                      
            % wheels
            Sf = [(x(32)-x(40)) (y(32)-y(40)) (z(32)-z(40))];
            Pf = [(x(32)-x(27)) (y(32)-y(27)) (z(32)-z(27))];
            R = cross(Sf,Pf);
            Rnorm = sqrt(R(1)^2+R(2)^2+R(3)^2);
            R = R/Rnorm;
             
            qx = x(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(1) + sin(app.theta)*Sf(1);
            qy = y(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(2) + sin(app.theta)*Sf(2);
            qz = z(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(3) + sin(app.theta)*Sf(3);
            set(app.frontwheel,'XData',qx','YData', qy,'ZData', qz);
            Sr = [(x(36)-x(39)) (y(36)-y(39)) (z(36)-z(39))];
            Pr = [(x(36)-x(31)) (y(36)-y(31)) (z(36)-z(31))];
            R = cross(Sr,Pr);
            Rrnorm = sqrt(R(1)^2+R(2)^2+R(3)^2);
            Rr = R/Rrnorm;
            sx = x(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(1) + sin(app.theta)*Sr(1);
            sy = y(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(2) + sin(app.theta)*Sr(2);
            sz = z(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(3) + sin(app.theta)*Sr(3);
            set(app.rearwheel,'XData',sx,'YData',sy,'ZData',sz);
            
        end

        function timeplotrider(app)
            n = min(app.Length, app.Index);
            x = -app.xn(n, :); y = -app.yn(n, :); z = -app.zn(n, :);
            % rider
            set(app.handlepuntr,'XData',x(1:20),'YData',y(1:20), 'ZData',z(1:20));
            set(app.handlerlba,'XData',x([1 2 3 4 9 10 11 15 14 13]),'YData',y([1 2 3 4 9 10 11 15 14 13]),'ZData',z([1 2 3 4 9 10 11 15 14 13]));
            set(app.handlela, 'XData',x([11 19 18 17]),'YData',y([11 19 18 17]),'ZData',z([11 19 18 17]));
            set(app.handlell, 'XData',x([5 6 7 8 9]),'YData',y([5 6 7 8 9]),'ZData',z([5 6 7 8 9]));    
        end

        function firstplot(app)
            % function for makeing an initial plot
            disableDefaultInteractivity(app.UIAxes); % Deshabilita la interactividad en los ejes
            app.UIAxes.HitTest = 'off';
            
            n = min(app.Length, app.Index);

            hold(app.UIAxes, "off")
            
            switch app.UserData
                case 1 % plot rider
                    x = -app.xn(n, :); y = -app.yn(n, :); z = -app.zn(n, :); 
                    app.handlepuntr = plot3(app.UIAxes, x(1:20), y(1:20), z(1:20),'o','MarkerFaceColor',[0 0 1],'MarkerSize',8);
                    hold(app.UIAxes, "on")
                    app.handlerlba = plot3(app.UIAxes, x([1 2 3 4 9 10 11 15 14 13]),y([1 2 3 4 9 10 11 15 14 13]), z([1 2 3 4 9 10 11 15 14 13]),'c');
                    app.handlela   = plot3(app.UIAxes, x([11 19 18 17]), y([11 19 18 17]), z(1,[11 19 18 17]),'c');
                    app.handlell   = plot3(app.UIAxes, x([5 6 7 8 9]), y([5 6 7 8 9]), z([5 6 7 8 9]),'c');
                    axis(app.UIAxes, [-(app.Data.uBikeRider(32)+0.8) -(app.Data.uBikeRider(32)-1.5) (-app.Data.uBikeRider(72)-1) (-app.Data.uBikeRider(72)+1.8) -0.2 2]);
                                   
                case 2 % plot bicycle
                    % x = app.xb(n, :); y = app.yb(n, :); z = app.zb(n, :); 
                    % % frame
                    % app.frontfork   = plot3(app.UIAxes, x([1 13  7]), y([ 1 13  7]), z([ 1 13  7]), 'r','LineWidth',2);
                    % hold(app.UIAxes, "on")
                    % app.handlebar  = plot3(app.UIAxes, x([3 18  9]), y([ 3 18  9]), z([ 3 18  9]), 'r','LineWidth',2);
                    % app.stem       = plot3(app.UIAxes, x([13 18]), y([13 18]), z([13 18]), 'r','LineWidth',2);
                    % app.rearfork   = plot3(app.UIAxes, x([17 5 6 11 17 13  6 17]), y([17  5  6 11 17 13  6 17]), z([17  5  6 11 17 13  6 17]), 'r','LineWidth',2);
                    % % wheels
                    % % front
                    % Sf = [(x(12)-x(20)) (y(12)-y(20)) (z(12)-z(20))];
                    % Pf = [(x(12)-x(7)) (y(12)-y(7)) (z(12)-z(7))];
                    % R = cross(Sf,Pf);
                    % Rnorm = sqrt(R(1)^2+R(2)^2+R(3)^2);
                    % R = R/Rnorm;
                    % theta = 0:pi/20:2*pi;
                    % qx= x(12)*ones(1,41)+ app.Data.rf(n)*cos(theta)*R(1) + sin(theta)*Sf(1);
                    % qy= y(12)*ones(1,41)+ app.Data.rf(n)*cos(theta)*R(2) + sin(theta)*Sf(2);
                    % qz= z(12)*ones(1,41)+ app.Data.rf(n)*cos(theta)*R(3) + sin(theta)*Sf(3);
                    % app.frontwheel = plot3(app.UIAxes, qx, qy, qz,'r','LineWidth',4);
                    % % rear
                    % Sr = [(x(16)-x(19)) (y(16)-y(19)) (z(16)-z(19))];
                    % Pr = [(x(16)-x(11)) (y(16)-y(11)) (z(16)-z(11))];
                    % R = cross(Sr,Pr);
                    % Rrnorm = sqrt(R(1)^2+R(2)^2+R(3)^2);
                    % Rr = R/Rrnorm;
                    % sx = x(16)*ones(1,41)+ app.Data.rr(n)*cos(theta)*Rr(1) + sin(theta)*Sr(1);
                    % sy = y(16)*ones(1,41)+ app.Data.rr(n)*cos(theta)*Rr(2) + sin(theta)*Sr(2);
                    % sz = z(16)*ones(1,41)+ app.Data.rr(n)*cos(theta)*Rr(3) + sin(theta)*Sr(3);
                    % app.rearwheel = plot3(app.UIAxes, sx, sy,sz,'r','LineWidth',4);
                    % axis(app.UIAxes, [-(app.Data.uBike(12)+0.5) -(app.Data.uBike(12)-1.5) (-app.Data.uBike(32)-1) (-app.Data.uBike(32)+1.5) -0.2 1.8]);
                    x = -app.xn(n, :); y = -app.yn(n, :); z = -app.zn(n, :); 
                    % frame
                    app.frontfork   = plot3(app.UIAxes, x([21 33 27]), y([21 33 27]), z([21 33 27]), 'r','LineWidth',2);
                    hold(app.UIAxes, "on")
                    app.handlebar  = plot3(app.UIAxes, x([23 38 29]), y([23 38 29]), z([23 38 29]), 'r','LineWidth',2);
                    app.stem       = plot3(app.UIAxes, x([33 38]), y([33 38]), z([33 38]), 'r','LineWidth',2);
                    app.rearfork   = plot3(app.UIAxes, x([37 25 26 31 37 33 26 37]), y([37 25 26 31 37 33 26 37]), z([37 25 26 31 37 33 26 37]), 'r','LineWidth',2);
                    % wheels
                    Sf = [(x(32)-x(40)) (y(32)-y(40)) (z(32)-z(40))];
                    Pf = [(x(32)-x(27)) (y(32)-y(27)) (z(32)-z(27))];
                    R = cross(Sf,Pf);
                    Rnorm = sqrt(R(1)^2+R(2)^2+R(3)^2);
                    R = R/Rnorm;
                    qx = x(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(1) + sin(app.theta)*Sf(1);
                    qy = y(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(2) + sin(app.theta)*Sf(2);
                    qz = z(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(3) + sin(app.theta)*Sf(3);
                    app.frontwheel = plot3(app.UIAxes, qx, qy, qz,'k','LineWidth',4);
                    Sr = [(x(36)-x(39)) (y(36)-y(39)) (z(36)-z(39))];
                    Pr = [(x(36)-x(31)) (y(36)-y(31)) (z(36)-z(31))];
                    R = cross(Sr,Pr);
                    Rrnorm = sqrt(R(1)^2+R(2)^2+R(3)^2);
                    Rr = R/Rrnorm;
                    sx = x(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(1) + sin(app.theta)*Sr(1);
                    sy = y(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(2) + sin(app.theta)*Sr(2);
                    sz = z(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(3) + sin(app.theta)*Sr(3);
                    app.rearwheel = plot3(app.UIAxes, sx, sy, sz,'k','LineWidth',4);
                    axis(app.UIAxes, [-(app.Data.uBikeRider(32)+0.8) -(app.Data.uBikeRider(32)-1.5) (-app.Data.uBikeRider(72)-1) (-app.Data.uBikeRider(72)+1.8) -0.2 2]);

                otherwise  % plot bicycle and rider
                    x = -app.xn(n, :); y = -app.yn(n, :); z = -app.zn(n, :); 
                    % rider
                    app.handlepuntr= plot3(app.UIAxes, x([1:20]), y([1:20]), z([1:20]),'o','MarkerFaceColor',[0 0 1],'MarkerSize',8);
                    hold(app.UIAxes, "on")
                    app.handlerlba = plot3(app.UIAxes, x([1 2 3 4 9 10 11 15 14 13]),y([1 2 3 4 9 10 11 15 14 13]), z([1 2 3 4 9 10 11 15 14 13]),'c');
                    app.handlela   = plot3(app.UIAxes, x([11 19 18 17]), y([11 19 18 17]), z([11 19 18 17]),'c');
                    app.handlell   = plot3(app.UIAxes, x([5 6 7 8 9]), y([5 6 7 8 9]), z([5 6 7 8 9]),'c');
                    % frame
                    app.frontfork   = plot3(app.UIAxes, x([21 33 27]), y([21 33 27]), z([21 33 27]), 'r','LineWidth',2);
                    app.handlebar  = plot3(app.UIAxes, x([23 38 29]), y([23 38 29]), z([23 38 29]), 'r','LineWidth',2);
                    app.stem       = plot3(app.UIAxes, x([33 38]), y([33 38]), z([33 38]), 'r','LineWidth',2);
                    app.rearfork   = plot3(app.UIAxes, x([37 25 26 31 37 33 26 37]), y([37 25 26 31 37 33 26 37]), z([37 25 26 31 37 33 26 37]), 'r','LineWidth',2);
                    % wheels
                    Sf = [(x(32)-x(40)) (y(32)-y(40)) (z(32)-z(40))];
                    Pf = [(x(32)-x(27)) (y(32)-y(27)) (z(32)-z(27))];
                    R = cross(Sf,Pf);
                    Rnorm = sqrt(R(1)^2+R(2)^2+R(3)^2);
                    R = R/Rnorm;
                    qx = x(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(1) + sin(app.theta)*Sf(1);
                    qy = y(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(2) + sin(app.theta)*Sf(2);
                    qz = z(32)*ones(1,21)+ app.Data.rf(n)*cos(app.theta)*R(3) + sin(app.theta)*Sf(3);
                    app.frontwheel = plot3(app.UIAxes, qx, qy, qz,'k','LineWidth',4);
                    Sr = [(x(36)-x(39)) (y(36)-y(39)) (z(36)-z(39))];
                    Pr = [(x(36)-x(31)) (y(36)-y(31)) (z(36)-z(31))];
                    R = cross(Sr,Pr);
                    Rrnorm = sqrt(R(1)^2+R(2)^2+R(3)^2);
                    Rr = R/Rrnorm;
                    sx = x(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(1) + sin(app.theta)*Sr(1);
                    sy = y(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(2) + sin(app.theta)*Sr(2);
                    sz = z(36)*ones(1,21)+ app.Data.rr(n)*cos(app.theta)*Rr(3) + sin(app.theta)*Sr(3);
                    app.rearwheel = plot3(app.UIAxes, sx, sy, sz,'k','LineWidth',4);
                    axis(app.UIAxes, [-(app.Data.uBikeRider(32)+0.8) -(app.Data.uBikeRider(32)-1.5) (-app.Data.uBikeRider(72)-1) (-app.Data.uBikeRider(72)+1.8) -0.2 2]);
            
            end

            % axis(app.UIAxes, "equal")
            hold(app.UIAxes, "off")

            UpdateOrientation(app)
                              
        end

        function UpdateOrientation(app)

            if (get(app.D2_radiobutton,'Value') == 1) % 2D plot
                if (get(app.Side_radiobutton,'Value') == 1) % side view
                    AZ = 0;
                else % front View
                    AZ = 270;
                end
                if (get(app.top_radiobutton,'Value') == 1) % top view
                    EL = 90;
                else % horizontal view
                    EL = 0;
                end
            else % 3D view
                AZ = -50;
                EL = 30;
            end
            view(app.UIAxes, AZ, EL)
        end

        function Step(app)
            
            if app.Index >= app.Length
                app.Stop = 1;
                app.Index = 1;
                stop(app.Timer);
            elseif app.Stop
                stop(app.Timer);
                return;
            else
                hold(app.UIAxes, 'on')
                switch app.UserData
                    case 1                            
                        timeplotrider(app)
                    case 2
                        timeplotbike(app)                            
                    otherwise                                                 
                        timeplotboth(app)                            
                end                    
                app.Index = min(app.Index + 1 + app.jumpProces, app.Length);     
                hold(app.UIAxes, 'off')
            end                
            set(app.TimesSlider, 'Value', app.Index * app.dt);
            set(app.plotx, 'Value', app.Data.t(app.Index))
            set(app.UIAxes2, 'XLim', FindLims(app, app.dPlotX))
            
        end
    
        function setupTimer(app)
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                              'Period', app.dt*(1 + app.jumpProces), ...
                              'TimerFcn', @(~,~)Step(app));

        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DeactivateAll(app);
            app.UIFigure.Renderer = 'opengl';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.Stop = 1;
            [fileName, pathName] = uigetfile( ...
                   {'*.mat', 'Mat Files (*.mat)'; ...
                    '*.*', 'All Files (*.*)'});
            
            if pathName == 0
                return
            end
            
            ActivateAll(app);
            app.FileName = fileName;
            app.Data = load(fullfile(pathName,fileName));

            app.Index = 1;
            
            if app.UserData ~= 1 || app.UserData ~= 2
                app.UserData = 3;
            end
            
            app.xn = app.Data.xn;
            app.yn = app.Data.yn;
            app.zn = app.Data.zn;

            app.t = app.Data.t;

            app.Length = size(app.xn,1);

            firstplot(app)

            % which rider?
            nr = (textscan(fileName,'%d',1));
            if nr{1} >=3000
                app.rider=('Jason');
            elseif nr{1}>=2000
                app.rider=('Victor');
            else 
                app.rider=('Jodi');
            end

            UpdateText(app);
            
            app.TimesSlider.Limits = [0, round(app.Data.t(end))];

            activeTimers = timerfind;

            if ~isempty(activeTimers)
                stop(activeTimers);  % Detiene los timers
                pause(0.01)
                delete(activeTimers);  % Elimina los timers
            end
            
            setupTimer(app);
            firstQplots(app);

        end

        % Value changing function: TimesSlider
        function TimesSliderValueChanging(app, event)
            app.Stop = 1;
            app.Index = max(1,min(app.Length, round(event.Value/app.dt)));
            firstplot(app)
        end

        % Button pushed function: play_button
        function play_buttonButtonPushed(app, event)
            
            if strcmp(app.Timer.Running, 'off') % Check if the timer is stoped
                app.Stop = 0;
                start(app.Timer); 
            end
      
        end

        % Button pushed function: stop_button
        function stop_buttonButtonPushed(app, event)
            app.Stop =  1;
        end

        % Button pushed function: reset_button
        function reset_buttonButtonPushed(app, event)
            stop(app.Timer);
            app.Stop = 1;
            app.Index = 1;
            firstplot(app)
            set(app.TimesSlider, 'Value', app.Index * app.dt);  % Update TimeSlider
            set(app.plotx, 'Value', app.Data.t(app.Index))
            set(app.UIAxes2, 'XLim', FindLims(app, app.dPlotX))
        end

        % Button pushed function: rider_pushbutton
        function rider_pushbuttonButtonPushed(app, event)
            app.UserData = 1;
            app.Stop = 1;
            firstplot(app)
            app.Stop = 0;
            start(app.Timer); 

        end

        % Button pushed function: bicycle_pushbutton
        function bicycle_pushbuttonButtonPushed(app, event)
            app.UserData = 2;
            app.Stop = 1;
            firstplot(app)
            app.Stop = 0;
            start(app.Timer); 
            
        end

        % Button pushed function: both_pushbutton
        function both_pushbuttonButtonPushed(app, event)
            app.UserData = 3;
            app.Stop = 1;
            firstplot(app)
            app.Stop = 0;
            start(app.Timer); 
           
        end

        % Selection changed function: Side_uipanel
        function Side_uipanelSelectionChanged(app, event)
            UpdateOrientation(app)
        end

        % Selection changed function: Dimension_uipanel
        function Dimension_uipanelSelectionChanged(app, event)
            UpdateOrientation(app)
            
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
            if strcmp(app.Timer.Running, 'off')
                firstQplots(app)
            else
                app.Stop = 1;
                stop(timer);
                firstQplots(app)
                app.Stop = 0;
                start(app.Timer); 
            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            activeTimers = timerfind;
            if ~isempty(activeTimers)
                stop(activeTimers);  % Detiene los timers
                delete(activeTimers);  % Elimina los timers
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Experiment')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'General Coordinates')
            xlabel(app.UIAxes2, 'time [s]')
            ylabel(app.UIAxes2, 'Angles [deg]')
            zlabel(app.UIAxes2, 'Z')
            app.ViewOrientation_uipanel = uipanel(app.UIFigure);
            app.ViewOrientation_uipanel.Title = 'Viewing Experiment Plot';
            app.Dimension_uipanel = uibuttongroup(app.ViewOrientation_uipanel);
            app.Dimension_uipanel.SelectionChangedFcn = createCallbackFcn(app, @Dimension_uipanelSelectionChanged, true);
            app.D2_radiobutton = uiradiobutton(app.Dimension_uipanel);
            app.D2_radiobutton.Text = '2 Dimensional ';
            app.D2_radiobutton.Value = true;
            app.radiobutton2 = uiradiobutton(app.Dimension_uipanel);
            app.radiobutton2.Text = '3 Dimensional';
            app.Side_uipanel = uibuttongroup(app.ViewOrientation_uipanel);
            app.Side_uipanel.SelectionChangedFcn = createCallbackFcn(app, @Side_uipanelSelectionChanged, true);
            app.Side_radiobutton = uiradiobutton(app.Side_uipanel);
            app.Side_radiobutton.Text = 'Side';
            app.Side_radiobutton.Value = true;
            app.Front_radiobutton = uiradiobutton(app.Side_uipanel);
            app.Front_radiobutton.Text = 'Front';
            app.top_radiobutton = uiradiobutton(app.Side_uipanel);
            app.top_radiobutton.Text = 'Top';
            app.bicycle_pushbutton = uibutton(app.ViewOrientation_uipanel, 'push');
            app.bicycle_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @bicycle_pushbuttonButtonPushed, true);
            app.bicycle_pushbutton.Text = 'Bicycle';
            app.rider_pushbutton = uibutton(app.ViewOrientation_uipanel, 'push');
            app.rider_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @rider_pushbuttonButtonPushed, true);
            app.rider_pushbutton.Text = 'Rider';
            app.both_pushbutton = uibutton(app.ViewOrientation_uipanel, 'push');
            app.both_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @both_pushbuttonButtonPushed, true);
            app.both_pushbutton.Text = 'Both';
            app.uipanel17 = uipanel(app.UIFigure);
            app.uipanel17.Title = 'Controls';
            app.play_button = uibutton(app.uipanel17, 'push');
            app.play_button.ButtonPushedFcn = createCallbackFcn(app, @play_buttonButtonPushed, true);
            app.play_button.Text = 'Play';
            app.stop_button = uibutton(app.uipanel17, 'push');
            app.stop_button.ButtonPushedFcn = createCallbackFcn(app, @stop_buttonButtonPushed, true);
            app.stop_button.Text = 'Stop';
            app.reset_button = uibutton(app.uipanel17, 'push');
            app.reset_button.ButtonPushedFcn = createCallbackFcn(app, @reset_buttonButtonPushed, true);
            app.reset_button.Text = 'Reset';
            app.TimesSliderLabel = uilabel(app.UIFigure);
            app.TimesSliderLabel.Text = 'Time [s]';
            app.TimesSlider = uislider(app.UIFigure);
            app.TimesSlider.Limits = [0.1 100];
            app.TimesSlider.ValueChangingFcn = createCallbackFcn(app, @TimesSliderValueChanging, true);
            app.TimesSlider.Value = 0.5;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.BikeEditFieldLabel = uilabel(app.UIFigure);
            app.BikeEditFieldLabel.Text = 'Bike';
            app.BikeEditField = uieditfield(app.UIFigure, 'text');
            app.BikeEditField.Editable = 'off';
            app.ExperimentEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentEditField.Editable = 'off';
            app.SpeedkmhEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedkmhEditFieldLabel.Text = 'Speed (km/h)';
            app.SpeedkmhEditField = uieditfield(app.UIFigure, 'text');
            app.SpeedkmhEditField.Editable = 'off';
            app.GearEditFieldLabel = uilabel(app.UIFigure);
            app.GearEditFieldLabel.Text = 'Gear';
            app.GearEditField = uieditfield(app.UIFigure, 'numeric');
            app.GearEditField.Editable = 'off';
            app.RiderEditFieldLabel = uilabel(app.UIFigure);
            app.RiderEditFieldLabel.Text = 'Rider';
            app.RiderEditField = uieditfield(app.UIFigure, 'text');
            app.RiderEditField.Editable = 'off';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Editable = 'off';
            app.SelectPlotsGeneralCoordinatesPanel = uipanel(app.UIFigure);
            app.SelectPlotsGeneralCoordinatesPanel.Title = 'Select Plots General Coordinates';
            app.YawdegCheckBox = uicheckbox(app.SelectPlotsGeneralCoordinatesPanel);
            app.YawdegCheckBox.Text = 'Yaw [deg]';
            app.RolldegCheckBox = uicheckbox(app.SelectPlotsGeneralCoordinatesPanel);
            app.RolldegCheckBox.Text = 'Roll [deg';
            app.SteerAngledegCheckBox = uicheckbox(app.SelectPlotsGeneralCoordinatesPanel);
            app.SteerAngledegCheckBox.Text = 'Steer Angle [deg]';
            app.ApplyButton = uibutton(app.SelectPlotsGeneralCoordinatesPanel, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appVisualLite2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end